classdef Gear_Drive3_20260120165408 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_47            matlab.ui.control.Label
        Image3              matlab.ui.control.Image
        TabGroup            matlab.ui.container.TabGroup
        Tab_7               matlab.ui.container.Tab
        Panel_15            matlab.ui.container.Panel
        Image7              matlab.ui.control.Image
        Tab_6               matlab.ui.container.Tab
        Panel_14            matlab.ui.container.Panel
        Button_12           matlab.ui.control.StateButton
        Image6              matlab.ui.control.Image
        Tab_cssj            matlab.ui.container.Tab
        ButtonGroup_2       matlab.ui.container.ButtonGroup
        Button_8            matlab.ui.control.RadioButton
        Button_7            matlab.ui.control.RadioButton
        ButtonGroup         matlab.ui.container.ButtonGroup
        Button_6            matlab.ui.control.RadioButton
        Button_5            matlab.ui.control.RadioButton
        Button_3            matlab.ui.control.RadioButton
        runButton           matlab.ui.control.StateButton
        dEditField_2        matlab.ui.control.NumericEditField
        dEditField_2Label   matlab.ui.control.Label
        Panel_12            matlab.ui.container.Panel
        Label_39            matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        TextArea_6          matlab.ui.control.TextArea
        Label_66            matlab.ui.control.Label
        TextArea_8          matlab.ui.control.TextArea
        TextArea_9          matlab.ui.control.TextArea
        Label_40            matlab.ui.control.Label
        Panel_10            matlab.ui.container.Panel
        DropDown            matlab.ui.control.DropDown
        TextArea_2          matlab.ui.control.TextArea
        Label_64            matlab.ui.control.Label
        TextArea_3          matlab.ui.control.TextArea
        Label_63            matlab.ui.control.Label
        TextArea_5          matlab.ui.control.TextArea
        Label_65            matlab.ui.control.Label
        Panel_2             matlab.ui.container.Panel
        runButton_2         matlab.ui.control.StateButton
        Button              matlab.ui.control.StateButton
        TextArea_10         matlab.ui.control.TextArea
        TextArea_4Label     matlab.ui.control.Label
        EditField_19        matlab.ui.control.NumericEditField
        Label_11            matlab.ui.control.Label
        EditField_18        matlab.ui.control.NumericEditField
        Label_10            matlab.ui.control.Label
        EditField_17        matlab.ui.control.NumericEditField
        Label_9             matlab.ui.control.Label
        EditField_16        matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        EditField_15        matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        EditField_82        matlab.ui.control.NumericEditField
        EditField_82Label   matlab.ui.control.Label
        EditField_14        matlab.ui.control.NumericEditField
        Label_17            matlab.ui.control.Label
        EditField_13        matlab.ui.control.NumericEditField
        Label_16            matlab.ui.control.Label
        EditField_11        matlab.ui.control.NumericEditField
        Label_15            matlab.ui.control.Label
        EditField_10        matlab.ui.control.NumericEditField
        Label_14            matlab.ui.control.Label
        EditField_9         matlab.ui.control.NumericEditField
        Label_13            matlab.ui.control.Label
        EditField_8         matlab.ui.control.NumericEditField
        Label_12            matlab.ui.control.Label
        PKWEditField        matlab.ui.control.NumericEditField
        PKWEditFieldLabel   matlab.ui.control.Label
        iEditField          matlab.ui.control.NumericEditField
        iEditFieldLabel     matlab.ui.control.Label
        EditField_23        matlab.ui.control.NumericEditField
        Label_5             matlab.ui.control.Label
        EditField_22        matlab.ui.control.NumericEditField
        EditField_22Label   matlab.ui.control.Label
        n1EditField         matlab.ui.control.NumericEditField
        n1Label             matlab.ui.control.Label
        Tab_sjcl            matlab.ui.container.Tab
        Label_50            matlab.ui.control.Label
        Label_48            matlab.ui.control.Label
        run3Button          matlab.ui.control.Button
        run4Button          matlab.ui.control.StateButton
        Button_9            matlab.ui.control.StateButton
        Panel_7             matlab.ui.container.Panel
        KH_EditField_2      matlab.ui.control.NumericEditField
        KHLabel_2           matlab.ui.control.Label
        b1EditField         matlab.ui.control.NumericEditField
        b1EditFieldLabel    matlab.ui.control.Label
        KFEditField         matlab.ui.control.NumericEditField
        KFEditFieldLabel    matlab.ui.control.Label
        KvEditField         matlab.ui.control.NumericEditField
        Kv1Label            matlab.ui.control.Label
        bhEditField         matlab.ui.control.NumericEditField
        bhEditFieldLabel    matlab.ui.control.Label
        vEditField          matlab.ui.control.NumericEditField
        vEditFieldLabel     matlab.ui.control.Label
        Zv2EditField        matlab.ui.control.NumericEditField
        Zv2Label            matlab.ui.control.Label
        Zv1EditField        matlab.ui.control.NumericEditField
        Zv1Label            matlab.ui.control.Label
        mnFEditField        matlab.ui.control.NumericEditField
        mnFEditFieldLabel   matlab.ui.control.Label
        d1FEditField        matlab.ui.control.NumericEditField
        d1FEditFieldLabel   matlab.ui.control.Label
        KFEditField_2       matlab.ui.control.NumericEditField
        KFLabel             matlab.ui.control.Label
        mntEditField        matlab.ui.control.NumericEditField
        mntEditFieldLabel   matlab.ui.control.Label
        KH_Panel_2          matlab.ui.container.Panel
        KH_EditField        matlab.ui.control.NumericEditField
        KH_EditFieldLabel   matlab.ui.control.Label
        EditField_62        matlab.ui.control.NumericEditField
        EditField_61        matlab.ui.control.NumericEditField
        EditField_60        matlab.ui.control.NumericEditField
        EditField_59        matlab.ui.control.NumericEditField
        N1N2Panel           matlab.ui.container.Panel
        KFN2EditField       matlab.ui.control.NumericEditField
        KFN2EditFieldLabel  matlab.ui.control.Label
        KFN1EditField       matlab.ui.control.NumericEditField
        KFN1EditFieldLabel  matlab.ui.control.Label
        YSa2Panel           matlab.ui.container.Panel
        YSa2EditField       matlab.ui.control.NumericEditField
        YSa2EditFieldLabel  matlab.ui.control.Label
        EditField_54        matlab.ui.control.NumericEditField
        EditField_53        matlab.ui.control.NumericEditField
        EditField_52        matlab.ui.control.NumericEditField
        EditField_51        matlab.ui.control.NumericEditField
        YSa1Panel           matlab.ui.container.Panel
        YSa1EditField       matlab.ui.control.NumericEditField
        YSa1EditFieldLabel  matlab.ui.control.Label
        EditField_50        matlab.ui.control.NumericEditField
        EditField_49        matlab.ui.control.NumericEditField
        EditField_48        matlab.ui.control.NumericEditField
        EditField_47        matlab.ui.control.NumericEditField
        YFa2Panel           matlab.ui.container.Panel
        YFa2EditField       matlab.ui.control.NumericEditField
        YFa2EditFieldLabel  matlab.ui.control.Label
        EditField_46        matlab.ui.control.NumericEditField
        EditField_45        matlab.ui.control.NumericEditField
        EditField_44        matlab.ui.control.NumericEditField
        EditField_43        matlab.ui.control.NumericEditField
        YFa1Panel           matlab.ui.container.Panel
        YFa1EditField       matlab.ui.control.NumericEditField
        YFa1EditFieldLabel  matlab.ui.control.Label
        EditField_42        matlab.ui.control.NumericEditField
        EditField_41        matlab.ui.control.NumericEditField
        EditField_40        matlab.ui.control.NumericEditField
        EditField_39        matlab.ui.control.NumericEditField
        KHPanel_3           matlab.ui.container.Panel
        KHEditField_2       matlab.ui.control.NumericEditField
        Label_29            matlab.ui.control.Label
        EditField_38        matlab.ui.control.NumericEditField
        EditField_37        matlab.ui.control.NumericEditField
        EditField_36        matlab.ui.control.NumericEditField
        EditField_35        matlab.ui.control.NumericEditField
        Panel_3             matlab.ui.container.Panel
        Label_49            matlab.ui.control.Label
        N2EditField         matlab.ui.control.NumericEditField
        N2EditFieldLabel    matlab.ui.control.Label
        N1EditField         matlab.ui.control.NumericEditField
        N1EditFieldLabel    matlab.ui.control.Label
        run1Button          matlab.ui.control.Button
        Panel_4             matlab.ui.container.Panel
        run2Button          matlab.ui.control.Button
        Label_51            matlab.ui.control.Label
        b0EditField         matlab.ui.control.NumericEditField
        b0EditFieldLabel    matlab.ui.control.Label
        KHN2EditField       matlab.ui.control.NumericEditField
        KHN2EditFieldLabel  matlab.ui.control.Label
        KHN1EditField       matlab.ui.control.NumericEditField
        KHN1EditFieldLabel  matlab.ui.control.Label
        Panel_8             matlab.ui.container.Panel
        KAButton            matlab.ui.control.Button
        mnHEditField        matlab.ui.control.NumericEditField
        mnHEditFieldLabel   matlab.ui.control.Label
        d1HEditField        matlab.ui.control.NumericEditField
        d1HEditFieldLabel   matlab.ui.control.Label
        KHEditField_9       matlab.ui.control.NumericEditField
        KHEditField_9Label  matlab.ui.control.Label
        KHEditField         matlab.ui.control.NumericEditField
        KHLabel             matlab.ui.control.Label
        KVEditField         matlab.ui.control.NumericEditField
        KVEditFieldLabel    matlab.ui.control.Label
        KAEditField         matlab.ui.control.NumericEditField
        Tab_jsjg            matlab.ui.container.Tab
        Button_10           matlab.ui.control.StateButton
        Panel_13            matlab.ui.container.Panel
        EditField_92        matlab.ui.control.NumericEditField
        Label_69            matlab.ui.control.Label
        EditField_91        matlab.ui.control.NumericEditField
        Label_68            matlab.ui.control.Label
        EditField_90        matlab.ui.control.NumericEditField
        Label_67            matlab.ui.control.Label
        EditField_86        matlab.ui.control.NumericEditField
        Label_55            matlab.ui.control.Label
        EditField_85        matlab.ui.control.NumericEditField
        Label_54            matlab.ui.control.Label
        EditField_84        matlab.ui.control.NumericEditField
        Label_53            matlab.ui.control.Label
        EditField_83        matlab.ui.control.NumericEditField
        Label_52            matlab.ui.control.Label
        EditField_81        matlab.ui.control.NumericEditField
        Label_46            matlab.ui.control.Label
        EditField_76        matlab.ui.control.NumericEditField
        Label_45            matlab.ui.control.Label
        EditField_75        matlab.ui.control.NumericEditField
        Label_44            matlab.ui.control.Label
        EditField_74        matlab.ui.control.NumericEditField
        Label_43            matlab.ui.control.Label
        EditField_73        matlab.ui.control.NumericEditField
        Label_42            matlab.ui.control.Label
        Panel_5             matlab.ui.container.Panel
        FaEditField         matlab.ui.control.NumericEditField
        FaEditFieldLabel    matlab.ui.control.Label
        FtEditField         matlab.ui.control.NumericEditField
        FtEditFieldLabel    matlab.ui.control.Label
        FrEditField         matlab.ui.control.NumericEditField
        FrEditFieldLabel    matlab.ui.control.Label
        EditField_33        matlab.ui.control.NumericEditField
        Label_20            matlab.ui.control.Label
        EditField_64        matlab.ui.control.NumericEditField
        Label_31            matlab.ui.control.Label
        EditField_63        matlab.ui.control.NumericEditField
        Label_30            matlab.ui.control.Label
        EditField_31        matlab.ui.control.NumericEditField
        Label_26            matlab.ui.control.Label
        EditField_30        matlab.ui.control.NumericEditField
        Label_25            matlab.ui.control.Label
        EditField_29        matlab.ui.control.NumericEditField
        Label_24            matlab.ui.control.Label
        EditField_28        matlab.ui.control.NumericEditField
        Label_23            matlab.ui.control.Label
        Panel_20            matlab.ui.container.Panel
        Image5              matlab.ui.control.Image
        EditField_70        matlab.ui.control.NumericEditField
        Label_37            matlab.ui.control.Label
        EditField_69        matlab.ui.control.NumericEditField
        Label_36            matlab.ui.control.Label
        EditField_66        matlab.ui.control.NumericEditField
        Label_33            matlab.ui.control.Label
        EditField_72        matlab.ui.control.EditField
        EditField_72Label   matlab.ui.control.Label
        EditField_34        matlab.ui.control.NumericEditField
        Label_28            matlab.ui.control.Label
        EditField_27        matlab.ui.control.NumericEditField
        Label_22            matlab.ui.control.Label
        Panel_19            matlab.ui.container.Panel
        Image5_2            matlab.ui.control.Image
        EditField_65        matlab.ui.control.NumericEditField
        Label_32            matlab.ui.control.Label
        EditField_32        matlab.ui.control.NumericEditField
        Label_27            matlab.ui.control.Label
        EditField_26        matlab.ui.control.NumericEditField
        Label_21            matlab.ui.control.Label
        EditField_71        matlab.ui.control.EditField
        EditField_71Label   matlab.ui.control.Label
        EditField_67        matlab.ui.control.NumericEditField
        Label_34            matlab.ui.control.Label
        EditField_68        matlab.ui.control.NumericEditField
        Label_35            matlab.ui.control.Label
        Tab_save            matlab.ui.container.Tab
        Panel_9             matlab.ui.container.Panel
        Button_14           matlab.ui.control.StateButton
        Button_13           matlab.ui.control.StateButton
        Image4              matlab.ui.control.Image
        WordButton          matlab.ui.control.StateButton
        TXTButton           matlab.ui.control.StateButton
        Tab_shuoming        matlab.ui.container.Tab
        Panel_18            matlab.ui.container.Panel
        Label_61            matlab.ui.control.Label
        Panel_17            matlab.ui.container.Panel
        TXTWordLabel        matlab.ui.control.Label
        Panel_16            matlab.ui.container.Panel
        Label_60            matlab.ui.control.Label
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CurrentSize = 1;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
    end

    methods (Access = public)

        function updateplot(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.KAEditField.Value=sz;
            app.CurrentColormap = c;


            % Re-enable the Plot Options button
            app.KAButton.Enable = 'on';
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Call updateplot to display an initial plot
            updateplot(app, app.CurrentSize, app.CurrentColormap)

        end

        % Button pushed function: run1Button
        function run1ButtonPushed(app, event)
            % 输入基本参数
            n1=app.n1EditField.Value;    %转速n1（输入轴的）
            i=app.iEditField.Value;            %传动比i
            years=app.EditField_15.Value;     % 工作年限
            ban=app.EditField_16.Value;        % 班制
            days=app.EditField_17.Value;          % 一年工作天数
            gd=app.EditField_18.Value;           %一班工作时间h
            Lh=years*ban*days*gd;
            j=app.EditField_14.Value;                %齿轮转一圈同一齿面啮合次数
            z1=app.EditField_10.Value;%初选小齿轮齿数；
            z2=ceil(z1*i);%初选大齿轮齿数(向上取整)
            u=z2/z1;
            %应力循环次数
            N1=60*n1*j*Lh;
            N2=N1/u;
            app.N1EditField.Value=N1;
            app.N2EditField.Value=N2;

            app.Panel_4.Enable =  'on';
            app.run2Button.Enable =  'on';
        end

        % Value changed function: run4Button
        function run4ButtonValueChanged(app, event)
            %% GO 的回调函数

            n1=app.n1EditField.Value;                                     % 转速n1（输入轴的）
            alpha=app.EditField_22.Value;                                 % 压力角α（°）
            beta=app.EditField_23.Value;                                  % 螺旋角β(°)
            i=app.iEditField.Value;                                       % 传动比i
            p=app.PKWEditField.Value;                                     % 输入功率P（kw）
            jd=app.EditField_82.Value;                                     % 精度
            j=app.EditField_14.Value;                                     % 齿轮转一圈同一齿面啮合次数
            years=app.EditField_15.Value;                                 % 工作年限
            ban=app.EditField_16.Value;                                   % 班制
            days=app.EditField_17.Value;                                  % 一年工作天数
            gd=app.EditField_18.Value;                 %一班工作时间h
            KHt=app.EditField_19.Value;                  %试选载荷系数
            ha=app.EditField_8.Value;                 %齿顶高系数
            cn=app.EditField_9.Value;             %齿根系数
            z1=app.EditField_10.Value;               %初选小齿轮齿数；
            z2=ceil(z1*i);                 %初选大齿轮齿数(向上取整)
            u=z2/z1;
            phi_d=app.dEditField_2.Value;                                   % 齿宽系数Φd（支成承对于小齿轮对称布置：0.9~1.4；支成承对于小齿轮非对称布置：0.7~1.15；小齿轮悬臂布置：0.4~0.6；）
            %失效概率为1%
            S=app.EditField_13.Value;%安全系数
            T1=9.55*10^6*(p/n1);
            %% 计算区域系数ZH
            alphat=atan(tan(pi*alpha/180)/cos(pi*beta/180))*180/pi;
            betab=atan(tan(pi*beta/180)*cos(pi*alphat/180))*180/pi;
            ZH=sqrt((2*cos(pi*betab/180))/cos(pi*alphat/180)/sin(pi*alphat/180));
            %% 接触疲劳强度用重合系数Zε(Z_epsilon)
            alphaat1=acos((z1*cos(pi*alphat/180))/(z1+2*ha*cos(pi*beta/180)))*180/pi;
            alphaat2=acos((z2*cos(pi*alphat/180))/(z2+2*ha*cos(pi*beta/180)))*180/pi;
            epsilon_alpha=(z1*(tan(alphaat1*pi/180)-tan(alphat*pi/180))+z2*(tan(alphaat2*pi/180)-tan(alphat*pi/180)))/(2*pi);
            epsilon_beta=(phi_d*z1*tan(beta*pi/180))/pi;
            Z_epsilon=sqrt(((4-epsilon_alpha)/3)*(1-epsilon_beta)+(epsilon_beta/epsilon_alpha));

            %% 齿根弯曲疲劳强度设计
            % 试选载荷系数
            KFt=app.EditField_19.Value;%试选

            %% 计算弯曲疲劳强度用重合度系数Yε(Y_epsilon)
            epsilon_alpha_v=epsilon_alpha/cos(betab*pi/180)^2;
            Y_epsilon=0.25+0.75/(epsilon_alpha_v);
            %计算弯曲疲劳强度螺旋交系数Yβ
            Y_beta=1-epsilon_beta*beta/120;

            %% 当量齿数 插值法求Y1
            zv1=z1/cos(beta*pi/180)^3;
            zv2=z2/cos(beta*pi/180)^3;
            app.Zv1EditField.Value=zv1;
            app.Zv2EditField.Value=zv2;
            %% 表10-5数据
            database = [17	18	19	20	21	22	23	24	25	26	27	28	29	30	35	40	45	50	60	70	80	90	100	150	200	inf;
                2.97	2.91	2.85	2.80	2.76	2.72	2.69	2.65	2.62	2.60	2.57	2.55	2.53	2.52	2.45	2.40	2.35	2.32	2.28	2.24	2.22	2.20	2.18	2.14	2.12	206;
                1.52	1.53	1.54	1.55	1.56	1.57	1.575	1.58	1.59	1.595	1.60	1.61	1.62	1.625	1.65	1.67	1.68	1.70	1.73	1.75	1.77	1.78	1.79	1.83	1.865	1.97];

            % 数据x1
            zv1 = app.Zv1EditField.Value;

            % 在第一行定位x1所在的列
            colIndex = find(database(1,:) == zv1);
            if isempty(colIndex)
                % 如果没有找到x1，则进行插值
                [~, sortedIndex] = sort(abs(database(1,:) - zv1)); % 根据距离x1的距离进行排序
                colIndex1 = sortedIndex(1); % 最近的数字所在列
                colIndex2 = sortedIndex(2); % 第二近的数字所在列

                % 进行线性插值
                x2 = interp1(database(1,[colIndex1 colIndex2]), database(2,[colIndex1 colIndex2]), zv1);
                x3 = interp1(database(1,[colIndex1 colIndex2]), database(3,[colIndex1 colIndex2]), zv1);

                % 获取插值点两侧的数据
                a1 = database(1, colIndex1);
                a2 = database(1, colIndex2);
                bb1 = database(2, colIndex1);
                bb2 = database(2, colIndex2);
                c1 = database(3, colIndex1);
                c2 = database(3, colIndex2);

            else
                % 在第一行定位到x1，直接取出第二行和第三行的数据
                x2 = database(2,colIndex);
                x3 = database(3,colIndex);
            end

            app.EditField_39.Value=a1;
            app.EditField_41.Value=a2;
            app.EditField_47.Value=a1;
            app.EditField_49.Value=a2;
            app.YFa1EditField.Value=x2;
            app.YSa1EditField.Value=x3;
            app.EditField_40.Value=bb1;
            app.EditField_42.Value=bb2;
            app.EditField_48.Value=c1;
            app.EditField_50.Value=c2;
            app.Button_9.Enable =  'on';

            %% Y2插值法

            zv2 = app.Zv2EditField.Value;

            % 在第一行定位x1所在的列
            colIndex = find(database(1,:) == zv2);
            if isempty(colIndex)
                % 如果没有找到x1，则进行插值
                [~, sortedIndex] = sort(abs(database(1,:) - zv2)); % 根据距离x1的距离进行排序
                colIndex1 = sortedIndex(1); % 最近的数字所在列
                colIndex2 = sortedIndex(2); % 第二近的数字所在列

                % 进行线性插值
                o2 = interp1(database(1,[colIndex1 colIndex2]), database(2,[colIndex1 colIndex2]), zv2);
                o3 = interp1(database(1,[colIndex1 colIndex2]), database(3,[colIndex1 colIndex2]), zv2);

                % 获取插值点两侧的数据
                q1 = database(1, colIndex1);
                q2 = database(1, colIndex2);
                w1 = database(2, colIndex1);
                w2 = database(2, colIndex2);
                e1 = database(3, colIndex1);
                e2 = database(3, colIndex2);

            else
                % 在第一行定位到x1，直接取出第二行和第三行的数据
                o2 = database(2,colIndex);
                o3 = database(3,colIndex);
            end

            app.EditField_43.Value=q1;
            app.EditField_45.Value=q2;
            app.EditField_51.Value=q1;
            app.EditField_53.Value=q2;
            app.YFa2EditField.Value=o2;
            app.YSa2EditField.Value=o3;
            app.EditField_44.Value=w1;
            app.EditField_46.Value=w2;
            app.EditField_52.Value=e1;
            app.EditField_54.Value=e2;


            %计算需用应力
            S1=app.EditField_13.Value;%取弯曲疲劳系数为1.4
            %查表10-20c也只与材料有关
            sigma_Flim11=str2double(app.TextArea_5.Value);
            sigma_Flim21=str2double(app.TextArea_6.Value);
            %查图10-18（根据上面N1和N2）
            KFN11=app.KFN1EditField.Value;
            KFN21=app.KFN2EditField.Value;
            %计算
            YFa1=app.YFa1EditField.Value;
            YFa2=app.YFa2EditField.Value;
            YSa1=app.YSa1EditField.Value;
            YSa2=app.YSa2EditField.Value;
            sigma_F1=KFN11*sigma_Flim11/S1;
            sigma_F2=KFN21*sigma_Flim21/S1;
            YF1=(YFa1*YSa1)/(sigma_F1);
            YF2=(YFa2*YSa2)/(sigma_F2);
            if YF1<YF2
                YF=YF2;
            else
                YF=YF1;
            end
            %%
            %计算齿轮模数
            mnH1=(((2*KFt*T1*Y_epsilon*Y_beta*(cos(beta*pi/180))^2)/(phi_d*z1^2))*YF)^(1/3);
            app.mntEditField.Value=mnH1;
            %% 调整齿轮模数
            %计算准备
            %圆周速度
            d1=(mnH1*z1)/cos(beta*pi/180);
            v1=(pi*d1*n1)/(60*1000);
            %齿宽
            b1=phi_d*d1;
            %宽高比ui
            h=(2*ha+cn)*mnH1;
            ui=b1/h;
            app.b1EditField.Value=b1;
            app.vEditField.Value=v1;
            app.bhEditField.Value=ui;


            %% 计算实际载荷系数KF
            %查图10-8获得载荷系数Kv1
            jd=app.EditField_82.Value;
            %查图10-8（根据v1和jd）
            Kv1=app.KvEditField.Value;%动载荷系数
            Ft1=2*T1/d1;
            %齿间载荷分配系数KF_alpha
            KA=app.KAEditField.Value;
            klll=KA*Ft1/b1;
            if klll>=100
                switch jd
                    case 5
                        KF_alpha=1.0;
                    case 6
                        KF_alpha=1.1;
                    case 7
                        KF_alpha=1.2;
                    case 8
                        KF_alpha=1.4;
                end
            else
                if klll<100
                    KF_alpha=1.5;%精度小于等于5(这里书上也没有小于100，精度大于5的值，暂时给给大于1.4的吧)

                end
            end

            app.KFEditField_2.Value=KF_alpha;


            %% 由表10-4用插值法得到KH_beta1

            b1=app.b1EditField.Value;
            bx=app.EditField_35.Value;
            cx=app.EditField_37.Value;
            by=app.EditField_36.Value;
            cy=app.EditField_38.Value;
            % 计算
            KH_beta1=(bx*cy-cx*by+b1*by-b1*cy)/(bx-cx);

            app.KH_EditField.Value=KH_beta1;
            app.KH_EditField_2.Value=KH_beta1;

            %查图10-13根据ui
            KA=app.KAEditField.Value;
            KF_beta1=app.KFEditField.Value;
            KF1=KA*Kv1*KF_alpha*KF_beta1;
            mnF=mnH1*(KF1/KFt)^(1/3);%计算实际载荷算得的齿轮模数
            d1F1=mnF*z1/cos(beta*pi/180);%相应小齿轮分度圆直径

            app.mnFEditField.Value=mnF;
            app.d1FEditField.Value=d1F1;

            %取模数m
            m=ceil(mnF);
            %分度圆直径
            d1H=app.d1HEditField.Value;
            if  d1F1<d1H
                dH=d1H;
            else
                dH=d1F1;
            end
            %计算小齿轮齿数
            z1=dH*cos(beta*pi/180)/m;
            z1=round(z1);
            z2=z1*u;
            z2=round(z2);
            uu=z2/z1;
            %计算中心距
            a=((z1+z2)*m)/(2*cos(beta*pi/180));
            a=round(a);
            %% 修正螺旋角
            beta0=(acos(((z1+z2)*m)/(2*a)))*180/pi;
            %计算分度圆直径
            d1=z1*m/cos(beta0*pi/180);
            d2=z2*m/cos(beta0*pi/180);
            app.EditField_65.Value=d1;
            app.EditField_66.Value=d2;
            %计算齿宽
            b=phi_d*d1;
            b2=round(b);%小齿轮齿宽
            b1=b2+5;%大齿轮齿宽

            ha2 = m*ha;
            hf = m*(ha+cn);

            %% 结果输出
            app.EditField_26.Value=z1;
            app.EditField_27.Value=z2;
            app.EditField_28.Value=m;
            app.EditField_29.Value=alpha;
            app.EditField_30.Value=beta0;
            app.EditField_31.Value=a;
            app.EditField_34.Value=b2;
            app.EditField_32.Value=b1;
            app.EditField_33.Value=uu;
            app.EditField_63.Value=ha2;
            app.EditField_64.Value=hf;


            %% 结构设计
            % 齿顶圆直径da
            da1 = m*z1/cos(beta0*pi/180) + 2*m*ha;
            da2 = m*z2/cos(beta0*pi/180) + 2*m*ha;
            app.EditField_67.Value=da1;
            app.EditField_68.Value=da2;

            df1 = m*z1/cos(beta0*pi/180) - 2*m*ha;
            df2 = m*z2/cos(beta0*pi/180) - 2*m*ha;
            app.EditField_69.Value=df1;
            app.EditField_70.Value=df2;


            if da1 <= 160
                app.EditField_71.Value = '实心式';
                app.Image5_2.ImageSource = '实心式.png';
            else
                if (160 < da1) && (da1 <= 500)
                    app.EditField_71.Value = '腹板式';
                    app.Image5_2.ImageSource = '腹板式.png';
                else
                    if (500 < da1) && (da1 <= 1000)
                        app.EditField_71.Value = '轮辐式';
                        app.Image5_2.ImageSource = '轮辐式.png';
                    end
                end
            end

            
            if da2 <= 160
                app.EditField_72.Value = '实心式';
                app.Image5.ImageSource = '实心式.png';
            else
                if (160 < da2) && (da2 <= 500)
                    app.EditField_72.Value = '腹板式';
                    app.Image5.ImageSource = '腹板式.png';
                else
                    if (500 < da2) && (da2 <= 1000)
                        app.EditField_72.Value = '轮辐式';
                        app.Image5.ImageSource = '轮辐式.png';
                    end
                end
            end


            %% 初始参数重现
            z01=app.EditField_10.Value;
            z02=app.EditField_11.Value;
            a01=app.EditField_22.Value;
            b01=app.EditField_23.Value;
            i01=app.iEditField.Value;

            xjc=str2double(app.TextArea_2.Value);
            xwq=str2double(app.TextArea_5.Value);
            djc=str2double(app.TextArea_8.Value);
            dwq=str2double(app.TextArea_6.Value);

            app.EditField_73.Value=z01;
            app.EditField_74.Value=z02;
            app.EditField_75.Value=a01;
            app.EditField_76.Value=b01;
            app.EditField_81.Value=i01;

            app.EditField_83.Value=xjc;
            app.EditField_84.Value=xwq;
            app.EditField_85.Value=djc;
            app.EditField_86.Value=dwq;


        end

        % Button pushed function: run2Button
        function run2ButtonPushed(app, event)
            % run2的回调函数

            ZE = str2double(app.TextArea_10.Value);                                      %弹性影响系数（查表10-6）
            sigma_Hlim1 = str2double(app.TextArea_2.Value);                             %查表得小齿轮接触疲劳极限()MPa
            sigma_Hlim2 = str2double(app.TextArea_8.Value);                             %查表得大齿轮接触疲劳极限()MPa
            %上面的参数都是根据材料定的
            n1 = app.n1EditField.Value;                                     % 转速n1（输入轴的）
            alpha = app.EditField_22.Value;                                 % 压力角α（°）
            beta = app.EditField_23.Value;                                  % 螺旋角β(°)
            i = app.iEditField.Value;                                       % 传动比i
            p = app.PKWEditField.Value;                                     % 输入功率P（kw）
            KHt = app.EditField_19.Value;                  %试选载荷系数
            ha = app.EditField_8.Value;                 %齿顶高系数
            z1 = app.EditField_10.Value;               %初选小齿轮齿数；
            z2 = ceil(z1*i);                 %初选大齿轮齿数(向上取整)
            u = z2/z1;
            phi_d = app.dEditField_2.Value;                                   % 齿宽系数Φd（支成承对于小齿轮对称布置：0.9~1.4；支成承对于小齿轮非对称布置：0.7~1.15；小齿轮悬臂布置：0.4~0.6；）
            %失效概率为1%
            S = app.EditField_13.Value;%安全系数
            T1 = 9.55*10^6*(p/n1);
            %% 计算区域系数ZH
            alphat = atan(tan(pi*alpha/180)/cos(pi*beta/180))*180/pi;
            betab = atan(tan(pi*beta/180)*cos(pi*alphat/180))*180/pi;
            ZH = sqrt((2*cos(pi*betab/180))/cos(pi*alphat/180)/sin(pi*alphat/180));
            %% 接触疲劳强度用重合系数Zε(Z_epsilon)
            alphaat1 = acos((z1*cos(pi*alphat/180))/(z1+2*ha*cos(pi*beta/180)))*180/pi;
            alphaat2 = acos((z2*cos(pi*alphat/180))/(z2+2*ha*cos(pi*beta/180)))*180/pi;
            epsilon_alpha = (z1*(tan(alphaat1*pi/180)-tan(alphat*pi/180))+z2*(tan(alphaat2*pi/180)-tan(alphat*pi/180)))/(2*pi);
            epsilon_beta = (phi_d*z1*tan(beta*pi/180))/pi;
            Z_epsilon = sqrt(((4-epsilon_alpha)/3)*(1-epsilon_beta)+(epsilon_beta/epsilon_alpha));
            %% 螺旋角系数Zβ(Z_beta)
            Z_beta = sqrt(cos(beta*pi/180));
            %查图10-19获取接触疲劳寿命系数KHN（根据上面N1和N2）
            KHN1 = app.KHN1EditField.Value;
            KHN2 = app.KHN2EditField.Value;
            %计算
            sigma_H1 = (KHN1*sigma_Hlim1)/S;
            sigma_H2 = (KHN2*sigma_Hlim2)/S;
            sigma_H = min(sigma_H1, sigma_H2);

            %% 计算小齿轮分度圆直径d1t
            d1t = (((2*KHt*T1)/ phi_d)*((u+1)/u)*((ZH*Z_epsilon*Z_beta*ZE)/(sigma_H))^2)^(1/3);
            %计算齿宽b
            b = phi_d*d1t;

            app.b0EditField.Value = b;

            app.run3Button.Enable =  'on';
            app.Panel_8.Enable =  'on';
            app.KAButton.Enable =  'on';
            app.KHPanel_3.Enable =  'on';
        end

        % Button pushed function: run3Button
        function run3ButtonPushed(app, event)

            ZE = str2double(app.TextArea_10.Value);                                      %弹性影响系数（查表10-6）
            sigma_Hlim1 = str2double(app.TextArea_2.Value);                             %查表得小齿轮接触疲劳极限()MPa
            sigma_Hlim2 = str2double(app.TextArea_8.Value);                             %查表得大齿轮接触疲劳极限()MPa
            %上面的参数都是根据材料定的
            jd = app.EditField_82.Value;
            n1 = app.n1EditField.Value;                                     % 转速n1（输入轴的）
            alpha = app.EditField_22.Value;                                 % 压力角α（°）
            beta = app.EditField_23.Value;                                  % 螺旋角β(°)
            i = app.iEditField.Value;                                       % 传动比i
            p = app.PKWEditField.Value;                                     % 输入功率P（kw）
            KHt = app.EditField_19.Value;                  %试选载荷系数
            ha = app.EditField_8.Value;                 %齿顶高系数
            z1 = app.EditField_10.Value;               %初选小齿轮齿数；
            z2 = ceil(z1*i);                 %初选大齿轮齿数(向上取整)
            u = z2/z1;
            phi_d = app.dEditField_2.Value;                                   % 齿宽系数Φd（支成承对于小齿轮对称布置：0.9~1.4；支成承对于小齿轮非对称布置：0.7~1.15；小齿轮悬臂布置：0.4~0.6；）
            %失效概率为1%
            S = app.EditField_13.Value;%安全系数
            T1 = 9.55*10^6*(p/n1);
            %% 计算区域系数ZH
            alphat = atan(tan(pi*alpha/180)/cos(pi*beta/180))*180/pi;
            betab = atan(tan(pi*beta/180)*cos(pi*alphat/180))*180/pi;
            ZH = sqrt((2*cos(pi*betab/180))/cos(pi*alphat/180)/sin(pi*alphat/180));
            %% 接触疲劳强度用重合系数Zε(Z_epsilon)
            alphaat1 = acos((z1*cos(pi*alphat/180))/(z1+2*ha*cos(pi*beta/180)))*180/pi;
            alphaat2 = acos((z2*cos(pi*alphat/180))/(z2+2*ha*cos(pi*beta/180)))*180/pi;
            epsilon_alpha = (z1*(tan(alphaat1*pi/180)-tan(alphat*pi/180))+z2*(tan(alphaat2*pi/180)-tan(alphat*pi/180)))/(2*pi);
            epsilon_beta = (phi_d*z1*tan(beta*pi/180))/pi;
            Z_epsilon = sqrt(((4-epsilon_alpha)/3)*(1-epsilon_beta)+(epsilon_beta/epsilon_alpha));
            %% 螺旋角系数Zβ(Z_beta)
            Z_beta = sqrt(cos(beta*pi/180));
            %查图10-19获取接触疲劳寿命系数KHN（根据上面N1和N2）
            KHN1 = app.KHN1EditField.Value;
            KHN2 = app.KHN2EditField.Value;
            %计算
            sigma_H1 = (KHN1*sigma_Hlim1)/S;
            sigma_H2 = (KHN2*sigma_Hlim2)/S;
            sigma_H = min(sigma_H1, sigma_H2);

            %% 计算小齿轮分度圆直径d1t
            d1t = (((2*KHt*T1)/ phi_d)*((u+1)/u)*((ZH*Z_epsilon*Z_beta*ZE)/(sigma_H))^2)^(1/3);
            b=app.b0EditField.Value;
            %% 计算实际载荷系数KH（运行第三次才是所需要数据）
            KA=app.KAEditField.Value;%查表10-2
            Kv=app.KVEditField.Value;%查图10-8动载荷系数
            %齿间载荷分配系数KH_alpha
            Ft1=2*T1/d1t;
            khh=KA*Ft1/b;
            if khh>=100
                switch jd
                    case 5
                        KH_alpha=1.0;
                    case 6
                        KH_alpha=1.1;
                    case 7
                        KH_alpha=1.2;
                    case 8
                        KH_alpha=1.4;
                end
            else
                if khh<100

                    KH_alpha=1.5;%精度小于等于5

                end
            end
            app.KHEditField.Value=KH_alpha;

            %表10-4（插值法）
            bx=app.EditField_35.Value;
            cx=app.EditField_37.Value;
            by=app.EditField_36.Value;
            cy=app.EditField_38.Value;
            % 计算
            KH_beta=(bx*cy-cx*by+b*by-b*cy)/(bx-cx);

            %计算分度圆直径

            % 输出插值结果
            app.KHEditField_2.Value=KH_beta;
            %载荷系数
            KH=KA*Kv*KH_alpha*KH_beta;
            app.KHEditField_9.Value=KH;
            %相应齿数模数

            d1H=d1t*(KH/KHt)^(1/3);
            mnH=(d1H*cos(beta*pi/180))/z1;

            app.d1HEditField.Value=d1H;
            app.mnHEditField.Value=mnH;

            app.run4Button.Enable =  'on';
            app.Panel_7.Enable =  'on';
            app.YFa1Panel.Enable =  'on';
            app.YFa2Panel.Enable =  'on';
            app.KH_Panel_2.Enable =  'on';
            app.YSa1Panel.Enable =  'on';
            app.YSa2Panel.Enable =  'on';
            app.N1N2Panel.Enable =  'on';
        end

        % Value changed function: TXTButton
        function TXTButtonValueChanged(app, event)
            %% 结果输出
            % 参数
            z1=app.EditField_26.Value;
            z2=app.EditField_27.Value;
            m=app.EditField_28.Value;
            alpha=app.EditField_29.Value;
            beta=app.EditField_30.Value;
            a=app.EditField_31.Value;
            b1=app.EditField_32.Value;
            b2=app.EditField_34.Value;
            jd=app.EditField_82.Value;
            i=app.iEditField.Value;
            i2=app.EditField_33.Value;


            % 创建并打开文本文件
            fileID = fopen('齿轮参数设计结果.txt', 'w');
            % 将结果写入文件
            fprintf(fileID, '齿轮参数设计结果\n');
            fprintf(fileID, '小齿轮齿数： %d\n', z1);
            fprintf(fileID, '大齿轮齿数： %d\n', z2);
            fprintf(fileID, '模数： %d mm\n', m);
            fprintf(fileID, '压力角： %d°\n', alpha);
            fprintf(fileID, '螺旋角： %f°\n', beta);
            fprintf(fileID, '中心距： %d mm\n', a);
            fprintf(fileID, '分配的传动比： %f\n',i);
            fprintf(fileID, '实际传动比： %f\n', i2);

            fprintf(fileID, '小齿轮齿宽： %d mm\n',b1);
            fprintf(fileID, '大齿轮齿宽： %d mm\n',b2);
            fprintf(fileID, '精度： %d级\n', jd);

            %% 结构
            lx1=app.EditField_71.Value;
            lx2=app.EditField_72.Value;
            fprintf(fileID, '小齿轮采用： %s\n', lx1);
            fprintf(fileID, '大齿轮采用： %s\n', lx2);
            % 关闭文件
            fclose(fileID);

        end

        % Value changed function: WordButton
        function WordButtonValueChanged(app, event)
            %% 结果输出
            % 参数
            z1=app.EditField_26.Value;
            z2=app.EditField_27.Value;
            m=app.EditField_28.Value;
            alpha=app.EditField_29.Value;
            beta=app.EditField_30.Value;
            a=app.EditField_31.Value;
            b1=app.EditField_32.Value;
            b2=app.EditField_34.Value;
            jd=app.EditField_82.Value;
            i=app.iEditField.Value;
            i2=app.EditField_33.Value;


            % 创建并打开文本文件
            fileID = fopen('齿轮参数设计结果.docx', 'w');
            % 将结果写入文件
            fprintf(fileID, '齿轮参数设计结果\n');
            fprintf(fileID, '小齿轮齿数： %d\n', z1);
            fprintf(fileID, '大齿轮齿数： %d\n', z2);
            fprintf(fileID, '模数： %d mm\n', m);
            fprintf(fileID, '压力角： %d°\n', alpha);
            fprintf(fileID, '螺旋角： %f°\n', beta);
            fprintf(fileID, '中心距： %d mm\n', a);
            fprintf(fileID, '分配的传动比： %f\n',i);
            fprintf(fileID, '实际传动比： %f\n', i2);

            fprintf(fileID, '小齿轮齿宽： %d mm\n',b1);
            fprintf(fileID, '大齿轮齿宽： %d mm\n',b2);
            fprintf(fileID, '精度： %d级\n', jd);

            %% 结构
            lx1=app.EditField_71.Value;
            lx2=app.EditField_72.Value;
            fprintf(fileID, '小齿轮采用： %s\n', lx1);
            fprintf(fileID, '大齿轮采用： %s\n', lx2);
            % 关闭文件
            fclose(fileID);
        end

        % Callback function
        function ExcelButtonValueChanged(app, event)

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            % 切换到选修卡组
            app.TabGroup.SelectedTab = app.Tab_sjcl;
            app.Panel_3.Enable =  'on';
            app.run1Button.Enable =  'on';
        end

        % Value changed function: runButton
        function runButtonValueChanged(app, event)

            %% KH KH_beta数据表
            global ZCFS1;global CLLX1;
            %% 判定齿宽系数
            if CLLX1==1
                ZCFS=ZCFS1;
                switch  ZCFS
                    case 1
                        fd=1.4;
                    case 2
                        fd=1;
                    case 3
                        fd=0.6;

                end
            else
                ZCFS=ZCFS1;
                switch  ZCFS
                    case 1
                        fd=1;
                    case 2
                        fd=0.8;
                    case 3
                        fd=0.4;

                end
            end
            app.dEditField_2.Value=fd;

            app.Panel_2.Enable =  'on';
            app.runButton_2.Enable =  'on';
        end

        % Callback function
        function ListBoxValueChanged(app, event)

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            global TR1;global CLLX1;
            TR1=1;
            switch selectedButton.Text
                case '软齿面齿轮'
                    CLLX1=1;
                case '硬齿面齿轮'
                    CLLX1=2;
            end
            app.ButtonGroup.Enable =  'on';
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            global TR2;global ZCFS1;
            TR2=2;
            switch selectedButton.Text
                case '对称布置'
                    ZCFS1=1;
                case '非对称布置'
                    ZCFS1=2;
                case '悬臂布置'
                    ZCFS1=3;
            end
            app.runButton.Enable =  'on';
            app.dEditField_2.Enable =  'on';
        end

        % Value changed function: Button_9
        function Button_9ValueChanged(app, event)
            p=app.PKWEditField.Value;                                     % 输入功率P（kw）
            n1=app.n1EditField.Value;                                     % 转速n1（输入轴的）
            ha = app.EditField_8.Value;
            c = app.EditField_9.Value; 
            d = app.EditField_65.Value;       %直径            
            
            app.EditField_90.Value = p;
            app.EditField_91.Value = ha;
            app.EditField_92.Value = c;

            T=9.55*10^6*(p/n1);


            alphan = app.EditField_29.Value*pi/180;   %法面压力角
            beta = app.EditField_30.Value*pi/180;     %螺旋角

            FT = (2*T)/d;
            FR = (FT*tan(alphan))/cos(beta);
            FA = FT*tan(beta);

            app.FtEditField.Value = FT;
            app.FrEditField.Value = FR;
            app.FaEditField.Value = FA;


            % 切换到选修卡组
            app.TabGroup.SelectedTab = app.Tab_jsjg;
            app.Panel_13.Enable =  'on';
            app.Panel_5.Enable =  'on';
            app.Panel_19.Enable =  'on';
            app.Panel_20.Enable =  'on';
            app.Button_10.Enable =  'on';


        end

        % Value changed function: Button_10
        function Button_10ValueChanged(app, event)
            value = app.Button_10.Value;
            % 切换到选修卡组
            app.TabGroup.SelectedTab = app.Tab_save;
            app.TXTButton.Enable =  'on';
            app.WordButton.Enable =  'on';
            app.Button_13.Enable =  'on';
            app.Button_14.Enable =  'on';

        end

        % Value changed function: EditField_73
        function EditField_73ValueChanged(app, event)

        end

        % Callback function
        function TreeSelectionChanged(app, event)

        end

        % Callback function
        function Tree3SelectionChanged(app, event)

        end

        % Value changed function: runButton_2
        function runButton_2ValueChanged(app, event)
            value = app.runButton_2.Value;
            % 输入基本参数
            i=app.iEditField.Value;            %传动比i
            z1=app.EditField_10.Value;%初选小齿轮齿数；
            z2=ceil(z1*i);%初选大齿轮齿数(向上取整)
            app.EditField_11.Value=z2;
            app.Button.Enable =  'on';
        end

        % Value changed function: Button_12
        function Button_12ValueChanged(app, event)

            value = app.Button_12.Value;
            % 切换到选修卡组
            app.TabGroup.SelectedTab = app.Tab_cssj;

            app.Panel_10.Enable =  'on';
            app.Panel_12.Enable =  'on';
            app.ButtonGroup_2.Enable =  'on';
        end

        % Value changed function: EditField_82
        function EditField_82ValueChanged(app, event)
            value = app.EditField_82.Value;

        end

        % Value changed function: dEditField_2
        function dEditField_2ValueChanged(app, event)
            value = app.dEditField_2.Value;

        end

        % Button pushed function: KAButton
        function KAButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.KAButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.DialogApp = Gear_KA(app, app.CurrentSize, app.CurrentColormap);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
        end

        % Value changed function: Button_13
        function Button_13ValueChanged(app, event)
            run  External_meshing_cylindrical_gear_transmission_app.mlapp
            % Disable Plot Options button while dialog is open
            m0 = app.EditField_28.Value;
            z1_0 = app.EditField_26.Value;
            z2_0 =app.EditField_27.Value;
            a0 = app.EditField_29.Value;
            ha0 = app.EditField_8.Value;
            c0 = app.EditField_9.Value;

            % 创建结构体
            data.m = m0 ;
            data.z1_0 = z1_0;
            data.z2_0 = z2_0;
            data.a0 = a0 ;
            data.ha0 = ha0 ;
            data.c0 = c0 ;
            setappdata(0,'mesh',data)

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;

            switch value
                case '球墨铸铁'
                    app.TextArea_3.Value= '300';
                    app.TextArea_2.Value='630';
                    app.TextArea_5.Value='215';

                case '可锻铸铁'
                    app.TextArea_3.Value= '250';
                    app.TextArea_2.Value='540';
                    app.TextArea_5.Value='195';

                case '灰铸铁'
                    app.TextArea_3.Value= '270';
                    app.TextArea_2.Value='455';
                    app.TextArea_5.Value='78';

                case '正火处理的结构钢'
                    app.TextArea_3.Value= '210';
                    app.TextArea_2.Value='485';
                    app.TextArea_5.Value='195';

                case '正火处理的铸钢'
                    app.TextArea_3.Value= '190';
                    app.TextArea_2.Value='375';
                    app.TextArea_5.Value='160';

                case '合金钢调质'
                    app.TextArea_3.Value= '360';
                    app.TextArea_2.Value='840';
                    app.TextArea_5.Value='305';

                case '合金铸钢调质'
                    app.TextArea_3.Value= '360';
                    app.TextArea_2.Value='760';
                    app.TextArea_5.Value='255';

                case '碳钢调质'
                    app.TextArea_3.Value= '210';
                    app.TextArea_2.Value='550';
                    app.TextArea_5.Value='210';
                case '碳素铸钢调质'
                    app.TextArea_3.Value= '210';
                    app.TextArea_2.Value= '490';
                    app.TextArea_5.Value='175';
                case '渗碳淬火钢'
                    app.TextArea_3.Value='59';
                    app.TextArea_2.Value='1475';
                    app.TextArea_5.Value='415';
                case '表面硬化钢'
                    app.TextArea_3.Value=  '100';
                    app.TextArea_2.Value='630';
                    app.TextArea_5.Value='215';
                case '调质、气体氮化处理的氮化钢(不含铝)'
                    app.TextArea_3.Value=  '59';
                    app.TextArea_2.Value='1285';
                    app.TextArea_5.Value='375';

                case '调质、气体氮化处理的调质钢'
                    app.TextArea_3.Value= '45';
                    app.TextArea_2.Value='1000';
                    app.TextArea_5.Value='320';

                case '调质或正火、碳氮共渗处理的调质钢'
                    app.TextArea_3.Value= '300';
                    app.TextArea_2.Value='930';
                    app.TextArea_5.Value='325';

            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            switch value
                case '球墨铸铁'
                    app.TextArea_9.Value= '300';
                    app.TextArea_8.Value='630';
                    app.TextArea_6.Value='215';

                case '可锻铸铁'
                    app.TextArea_9.Value= '250';
                    app.TextArea_8.Value='540';
                    app.TextArea_6.Value='195';

                case '灰铸铁'
                    app.TextArea_9.Value= '270';
                    app.TextArea_8.Value='455';
                    app.TextArea_6.Value='78';

                case '正火处理的结构钢'
                    app.TextArea_9.Value= '210';
                    app.TextArea_8.Value='485';
                    app.TextArea_6.Value='195';

                case '正火处理的铸钢'
                    app.TextArea_9.Value= '190';
                    app.TextArea_8.Value='375';
                    app.TextArea_6.Value='160';

                case '合金钢调质'
                    app.TextArea_9.Value= '360';
                    app.TextArea_8.Value='840';
                    app.TextArea_6.Value='305';

                case '合金铸钢调质'
                    app.TextArea_9.Value= '360';
                    app.TextArea_8.Value='760';
                    app.TextArea_6.Value='255';

                case '碳钢调质'
                    app.TextArea_9.Value= '210';
                    app.TextArea_8.Value='550';
                    app.TextArea_6.Value='210';
                case '碳素铸钢调质'
                    app.TextArea_9.Value= '210';
                    app.TextArea_8.Value= '490';
                    app.TextArea_6.Value='175';
                case '渗碳淬火钢'
                    app.TextArea_9.Value='59';
                    app.TextArea_8.Value='1475';
                    app.TextArea_6.Value='415';
                case '表面硬化钢'
                    app.TextArea_9.Value= '100';
                    app.TextArea_8.Value='630';
                    app.TextArea_6.Value='215';
                case '调质、气体氮化处理的氮化钢(不含铝)'
                    app.TextArea_9.Value= '59';
                    app.TextArea_8.Value='1285';
                    app.TextArea_6.Value='375';

                case '调质、气体氮化处理的调质钢'
                    app.TextArea_9.Value= '45';
                    app.TextArea_8.Value='1000';
                    app.TextArea_6.Value='320';

                case '调质或正火、碳氮共渗处理的调质钢'
                    app.TextArea_9.Value= '300';
                    app.TextArea_8.Value='930';
                    app.TextArea_6.Value='325';

            end

        end

        % Value changed function: Button_14
        function Button_14ValueChanged(app, event)
            value = app.Button_14.Value;
            run  Cylindrical_gear.mlapp
            % Disable Plot Options button while dialog is open
            m0 = app.EditField_28.Value;
            z1_0 = app.EditField_26.Value;
            a0 = app.EditField_29.Value;
            ha0 = app.EditField_8.Value;
            c0 = app.EditField_9.Value;
            b0 = app.EditField_30.Value;
            B0 = app.EditField_32.Value;

            % 创建结构体
            data.m = m0 ;
            data.z1_0 = z1_0;
            data.a0 = a0 ;
            data.ha0 = ha0 ;
            data.c0 = c0 ;            
            data.b0 = b0 ;
            data.B0 = B0 ;

            setappdata(0,'cy',data)

        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = '渐开线圆柱齿轮参数设计';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '失效形式';
            app.Panel_15 = uipanel(app.Tab_7);
            app.Image7 = uiimage(app.Panel_15);
            app.Image7.ImageSource = '齿轮失效形式.png';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '设计步骤';
            app.Panel_14 = uipanel(app.Tab_6);
            app.Image6 = uiimage(app.Panel_14);
            app.Image6.ImageSource = '设计步骤.png';
            app.Button_12 = uibutton(app.Panel_14, 'state');
            app.Button_12.ValueChangedFcn = createCallbackFcn(app, @Button_12ValueChanged, true);
            app.Button_12.Text = '开始设计';
            app.Tab_cssj = uitab(app.TabGroup);
            app.Tab_cssj.Title = '参数设计';
            app.Panel_2 = uipanel(app.Tab_cssj);
            app.Panel_2.Enable = 'off';
            app.Panel_2.Title = '传动参数设定';
            app.n1Label = uilabel(app.Panel_2);
            app.n1Label.Text = '小齿轮转速n1';
            app.n1EditField = uieditfield(app.Panel_2, 'numeric');
            app.n1EditField.Value = 480;
            app.EditField_22Label = uilabel(app.Panel_2);
            app.EditField_22Label.Text = '压力角α';
            app.EditField_22 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_22.Value = 20;
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '螺旋角β';
            app.EditField_23 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_23.Value = 14;
            app.iEditFieldLabel = uilabel(app.Panel_2);
            app.iEditFieldLabel.Text = '传动比i';
            app.iEditField = uieditfield(app.Panel_2, 'numeric');
            app.iEditField.Value = 3.055;
            app.PKWEditFieldLabel = uilabel(app.Panel_2);
            app.PKWEditFieldLabel.Text = '输入功率P/KW';
            app.PKWEditField = uieditfield(app.Panel_2, 'numeric');
            app.PKWEditField.Value = 2.53;
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = '齿顶高系数';
            app.EditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_8.Value = 1;
            app.Label_13 = uilabel(app.Panel_2);
            app.Label_13.Text = '齿根高系数';
            app.EditField_9 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_9.Value = 0.25;
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = '初选小齿轮齿数';
            app.EditField_10 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_10.Value = 26;
            app.Label_15 = uilabel(app.Panel_2);
            app.Label_15.Text = '大齿轮齿数';
            app.EditField_11 = uieditfield(app.Panel_2, 'numeric');
            app.Label_16 = uilabel(app.Panel_2);
            app.Label_16.Text = '安全系数';
            app.EditField_13 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_13.Value = 1;
            app.Label_17 = uilabel(app.Panel_2);
            app.Label_17.Text = '一圈齿面啮合次数';
            app.EditField_14 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_14.Value = 1;
            app.EditField_82Label = uilabel(app.Panel_2);
            app.EditField_82Label.Text = '精度';
            app.EditField_82 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_82.ValueChangedFcn = createCallbackFcn(app, @EditField_82ValueChanged, true);
            app.EditField_82.Value = 7;
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '工作年限';
            app.EditField_15 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_15.Value = 10;
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '班制';
            app.EditField_16 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_16.Value = 2;
            app.Label_9 = uilabel(app.Panel_2);
            app.Label_9.Text = '一年工作天数';
            app.EditField_17 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_17.Value = 300;
            app.Label_10 = uilabel(app.Panel_2);
            app.Label_10.Text = '一班工作时间';
            app.EditField_18 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_18.Value = 8;
            app.Label_11 = uilabel(app.Panel_2);
            app.Label_11.Text = '试选载荷系数';
            app.EditField_19 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_19.Value = 1.3;
            app.TextArea_4Label = uilabel(app.Panel_2);
            app.TextArea_4Label.Text = '弹性影响系数ZE';
            app.TextArea_10 = uitextarea(app.Panel_2);
            app.TextArea_10.Value = {'189.8'};
            app.Button = uibutton(app.Panel_2, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Enable = 'off';
            app.Button.Text = '下一步';
            app.runButton_2 = uibutton(app.Panel_2, 'state');
            app.runButton_2.ValueChangedFcn = createCallbackFcn(app, @runButton_2ValueChanged, true);
            app.runButton_2.Enable = 'off';
            app.runButton_2.Text = 'run';
            app.Panel_10 = uipanel(app.Tab_cssj);
            app.Panel_10.Enable = 'off';
            app.Panel_10.Title = '小齿轮材料';
            app.Label_65 = uilabel(app.Panel_10);
            app.Label_65.Text = '弯曲疲劳极限';
            app.TextArea_5 = uitextarea(app.Panel_10);
            app.TextArea_5.Value = {'500'};
            app.Label_63 = uilabel(app.Panel_10);
            app.Label_63.Text = '齿轮齿面硬度';
            app.TextArea_3 = uitextarea(app.Panel_10);
            app.TextArea_3.Value = {'280'};
            app.Label_64 = uilabel(app.Panel_10);
            app.Label_64.Text = '接触疲劳极限';
            app.TextArea_2 = uitextarea(app.Panel_10);
            app.TextArea_2.Value = {'600'};
            app.DropDown = uidropdown(app.Panel_10);
            app.DropDown.Items = {'请选择小齿轮的材料及热处理方式', '球墨铸铁', '可锻铸铁', '灰铸铁', '正火处理的结构钢', '正火处理的铸钢', '合金钢调质', '碳钢调质', '合金铸钢调质', '碳素铸钢调质', '渗碳淬火钢', '表面硬化钢', '调质、气体氮化处理的氮化钢(不含铝)', '调质、气体氮化处理的调质钢', '调质或正火、碳氮共渗处理的调质钢'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '请选择小齿轮的材料及热处理方式';
            app.Panel_12 = uipanel(app.Tab_cssj);
            app.Panel_12.Enable = 'off';
            app.Panel_12.Title = '大齿轮材料';
            app.Label_40 = uilabel(app.Panel_12);
            app.Label_40.Text = '齿轮齿面硬度';
            app.TextArea_9 = uitextarea(app.Panel_12);
            app.TextArea_9.Value = {'240'};
            app.TextArea_8 = uitextarea(app.Panel_12);
            app.TextArea_8.Value = {'550'};
            app.Label_66 = uilabel(app.Panel_12);
            app.Label_66.Text = '弯曲疲劳极限';
            app.TextArea_6 = uitextarea(app.Panel_12);
            app.TextArea_6.Value = {'320'};
            app.DropDown_2 = uidropdown(app.Panel_12);
            app.DropDown_2.Items = {'请选择小齿轮的材料及热处理方式', '球墨铸铁', '可锻铸铁', '灰铸铁', '正火处理的结构钢', '正火处理的铸钢', '合金钢调质', '碳钢调质', '合金铸钢调质', '碳素铸钢调质', '渗碳淬火钢', '表面硬化钢', '调质、气体氮化处理的氮化钢(不含铝)', '调质、气体氮化处理的调质钢', '调质或正火、碳氮共渗处理的调质钢'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '请选择小齿轮的材料及热处理方式';
            app.Label_39 = uilabel(app.Panel_12);
            app.Label_39.Text = '接触疲劳极限';
            app.dEditField_2Label = uilabel(app.Tab_cssj);
            app.dEditField_2Label.Text = '齿宽系数φd';
            app.dEditField_2 = uieditfield(app.Tab_cssj, 'numeric');
            app.dEditField_2.ValueChangedFcn = createCallbackFcn(app, @dEditField_2ValueChanged, true);
            app.dEditField_2.Enable = 'off';
            app.runButton = uibutton(app.Tab_cssj, 'state');
            app.runButton.ValueChangedFcn = createCallbackFcn(app, @runButtonValueChanged, true);
            app.runButton.Enable = 'off';
            app.runButton.Text = 'run';
            app.ButtonGroup = uibuttongroup(app.Tab_cssj);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Enable = 'off';
            app.ButtonGroup.Title = '支撑方式';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '对称布置';
            app.Button_3.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '非对称布置';
            app.Button_6 = uiradiobutton(app.ButtonGroup);
            app.Button_6.Text = '悬臂布置';
            app.ButtonGroup_2 = uibuttongroup(app.Tab_cssj);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Enable = 'off';
            app.ButtonGroup_2.Title = '齿面类型';
            app.Button_7 = uiradiobutton(app.ButtonGroup_2);
            app.Button_7.Text = '软齿面齿轮';
            app.Button_7.Value = true;
            app.Button_8 = uiradiobutton(app.ButtonGroup_2);
            app.Button_8.Text = '硬齿面齿轮';
            app.Tab_sjcl = uitab(app.TabGroup);
            app.Tab_sjcl.Title = '数据处理';
            app.Panel_8 = uipanel(app.Tab_sjcl);
            app.Panel_8.Enable = 'off';
            app.KAEditField = uieditfield(app.Panel_8, 'numeric');
            app.KAEditField.Value = 1;
            app.KVEditFieldLabel = uilabel(app.Panel_8);
            app.KVEditFieldLabel.Text = 'KV';
            app.KVEditField = uieditfield(app.Panel_8, 'numeric');
            app.KVEditField.Value = 1.04;
            app.KHLabel = uilabel(app.Panel_8);
            app.KHLabel.Text = 'KHα';
            app.KHEditField = uieditfield(app.Panel_8, 'numeric');
            app.KHEditField_9Label = uilabel(app.Panel_8);
            app.KHEditField_9Label.Text = 'KH';
            app.KHEditField_9 = uieditfield(app.Panel_8, 'numeric');
            app.d1HEditFieldLabel = uilabel(app.Panel_8);
            app.d1HEditFieldLabel.Text = 'd1H';
            app.d1HEditField = uieditfield(app.Panel_8, 'numeric');
            app.mnHEditFieldLabel = uilabel(app.Panel_8);
            app.mnHEditFieldLabel.Text = 'mnH';
            app.mnHEditField = uieditfield(app.Panel_8, 'numeric');
            app.KAButton = uibutton(app.Panel_8, 'push');
            app.KAButton.ButtonPushedFcn = createCallbackFcn(app, @KAButtonPushed, true);
            app.KAButton.Enable = 'off';
            app.KAButton.Text = '选KA';
            app.Panel_4 = uipanel(app.Tab_sjcl);
            app.Panel_4.Enable = 'off';
            app.KHN1EditFieldLabel = uilabel(app.Panel_4);
            app.KHN1EditFieldLabel.Text = 'KHN1';
            app.KHN1EditField = uieditfield(app.Panel_4, 'numeric');
            app.KHN1EditField.Value = 0.93;
            app.KHN2EditFieldLabel = uilabel(app.Panel_4);
            app.KHN2EditFieldLabel.Text = 'KHN2';
            app.KHN2EditField = uieditfield(app.Panel_4, 'numeric');
            app.KHN2EditField.Value = 0.94;
            app.b0EditFieldLabel = uilabel(app.Panel_4);
            app.b0EditFieldLabel.Text = '主动轮齿宽b0';
            app.b0EditField = uieditfield(app.Panel_4, 'numeric');
            app.Label_51 = uilabel(app.Panel_4);
            app.Label_51.Text = '接触循环应力（图10-19）';
            app.run2Button = uibutton(app.Panel_4, 'push');
            app.run2Button.ButtonPushedFcn = createCallbackFcn(app, @run2ButtonPushed, true);
            app.run2Button.Enable = 'off';
            app.run2Button.Text = 'run2';
            app.Panel_3 = uipanel(app.Tab_sjcl);
            app.Panel_3.Enable = 'off';
            app.run1Button = uibutton(app.Panel_3, 'push');
            app.run1Button.ButtonPushedFcn = createCallbackFcn(app, @run1ButtonPushed, true);
            app.run1Button.Enable = 'off';
            app.run1Button.Text = 'run1';
            app.N1EditFieldLabel = uilabel(app.Panel_3);
            app.N1EditFieldLabel.Text = 'N1';
            app.N1EditField = uieditfield(app.Panel_3, 'numeric');
            app.N2EditFieldLabel = uilabel(app.Panel_3);
            app.N2EditFieldLabel.Text = 'N2';
            app.N2EditField = uieditfield(app.Panel_3, 'numeric');
            app.Label_49 = uilabel(app.Panel_3);
            app.Label_49.Text = '应力循环次数';
            app.KHPanel_3 = uipanel(app.Tab_sjcl);
            app.KHPanel_3.Enable = 'off';
            app.KHPanel_3.Title = '查表10-4: KHβ';
            app.EditField_35 = uieditfield(app.KHPanel_3, 'numeric');
            app.EditField_35.Value = 40;
            app.EditField_36 = uieditfield(app.KHPanel_3, 'numeric');
            app.EditField_36.Value = 1.315;
            app.EditField_37 = uieditfield(app.KHPanel_3, 'numeric');
            app.EditField_37.Value = 80;
            app.EditField_38 = uieditfield(app.KHPanel_3, 'numeric');
            app.EditField_38.Value = 1.324;
            app.Label_29 = uilabel(app.KHPanel_3);
            app.Label_29.Text = 'KHβ';
            app.KHEditField_2 = uieditfield(app.KHPanel_3, 'numeric');
            app.YFa1Panel = uipanel(app.Tab_sjcl);
            app.YFa1Panel.Enable = 'off';
            app.YFa1Panel.Title = '查表10-5: YFa1';
            app.EditField_39 = uieditfield(app.YFa1Panel, 'numeric');
            app.EditField_40 = uieditfield(app.YFa1Panel, 'numeric');
            app.EditField_41 = uieditfield(app.YFa1Panel, 'numeric');
            app.EditField_42 = uieditfield(app.YFa1Panel, 'numeric');
            app.YFa1EditFieldLabel = uilabel(app.YFa1Panel);
            app.YFa1EditFieldLabel.Text = 'YFa1';
            app.YFa1EditField = uieditfield(app.YFa1Panel, 'numeric');
            app.YFa2Panel = uipanel(app.Tab_sjcl);
            app.YFa2Panel.Enable = 'off';
            app.YFa2Panel.Title = '查表10-5: YFa2';
            app.EditField_43 = uieditfield(app.YFa2Panel, 'numeric');
            app.EditField_44 = uieditfield(app.YFa2Panel, 'numeric');
            app.EditField_45 = uieditfield(app.YFa2Panel, 'numeric');
            app.EditField_46 = uieditfield(app.YFa2Panel, 'numeric');
            app.YFa2EditFieldLabel = uilabel(app.YFa2Panel);
            app.YFa2EditFieldLabel.Text = 'YFa2';
            app.YFa2EditField = uieditfield(app.YFa2Panel, 'numeric');
            app.YSa1Panel = uipanel(app.Tab_sjcl);
            app.YSa1Panel.Enable = 'off';
            app.YSa1Panel.Title = '查表10-5: YSa1';
            app.EditField_47 = uieditfield(app.YSa1Panel, 'numeric');
            app.EditField_48 = uieditfield(app.YSa1Panel, 'numeric');
            app.EditField_49 = uieditfield(app.YSa1Panel, 'numeric');
            app.EditField_50 = uieditfield(app.YSa1Panel, 'numeric');
            app.YSa1EditFieldLabel = uilabel(app.YSa1Panel);
            app.YSa1EditFieldLabel.Text = 'YSa1';
            app.YSa1EditField = uieditfield(app.YSa1Panel, 'numeric');
            app.YSa2Panel = uipanel(app.Tab_sjcl);
            app.YSa2Panel.Enable = 'off';
            app.YSa2Panel.Title = '查表10-5: YSa2';
            app.EditField_51 = uieditfield(app.YSa2Panel, 'numeric');
            app.EditField_52 = uieditfield(app.YSa2Panel, 'numeric');
            app.EditField_53 = uieditfield(app.YSa2Panel, 'numeric');
            app.EditField_54 = uieditfield(app.YSa2Panel, 'numeric');
            app.YSa2EditFieldLabel = uilabel(app.YSa2Panel);
            app.YSa2EditFieldLabel.Text = 'YSa2';
            app.YSa2EditField = uieditfield(app.YSa2Panel, 'numeric');
            app.N1N2Panel = uipanel(app.Tab_sjcl);
            app.N1N2Panel.Enable = 'off';
            app.N1N2Panel.Title = '查图10-18 (根据N1、N2)';
            app.KFN1EditFieldLabel = uilabel(app.N1N2Panel);
            app.KFN1EditFieldLabel.Text = 'KFN1';
            app.KFN1EditField = uieditfield(app.N1N2Panel, 'numeric');
            app.KFN1EditField.Value = 0.85;
            app.KFN2EditFieldLabel = uilabel(app.N1N2Panel);
            app.KFN2EditFieldLabel.Text = 'KFN2';
            app.KFN2EditField = uieditfield(app.N1N2Panel, 'numeric');
            app.KFN2EditField.Value = 0.89;
            app.KH_Panel_2 = uipanel(app.Tab_sjcl);
            app.KH_Panel_2.Enable = 'off';
            app.KH_Panel_2.Title = '查表10-4: KH_β';
            app.EditField_59 = uieditfield(app.KH_Panel_2, 'numeric');
            app.EditField_59.Value = 40;
            app.EditField_60 = uieditfield(app.KH_Panel_2, 'numeric');
            app.EditField_60.Value = 1.315;
            app.EditField_61 = uieditfield(app.KH_Panel_2, 'numeric');
            app.EditField_61.Value = 80;
            app.EditField_62 = uieditfield(app.KH_Panel_2, 'numeric');
            app.EditField_62.Value = 1.324;
            app.KH_EditFieldLabel = uilabel(app.KH_Panel_2);
            app.KH_EditFieldLabel.Text = 'KH_β';
            app.KH_EditField = uieditfield(app.KH_Panel_2, 'numeric');
            app.Panel_7 = uipanel(app.Tab_sjcl);
            app.Panel_7.Enable = 'off';
            app.mntEditFieldLabel = uilabel(app.Panel_7);
            app.mntEditFieldLabel.Text = 'mnt';
            app.mntEditField = uieditfield(app.Panel_7, 'numeric');
            app.KFLabel = uilabel(app.Panel_7);
            app.KFLabel.Text = 'KFα';
            app.KFEditField_2 = uieditfield(app.Panel_7, 'numeric');
            app.d1FEditFieldLabel = uilabel(app.Panel_7);
            app.d1FEditFieldLabel.Text = 'd1F';
            app.d1FEditField = uieditfield(app.Panel_7, 'numeric');
            app.mnFEditFieldLabel = uilabel(app.Panel_7);
            app.mnFEditFieldLabel.Text = 'mnF';
            app.mnFEditField = uieditfield(app.Panel_7, 'numeric');
            app.Zv1Label = uilabel(app.Panel_7);
            app.Zv1Label.Text = 'Zv1';
            app.Zv1EditField = uieditfield(app.Panel_7, 'numeric');
            app.Zv2Label = uilabel(app.Panel_7);
            app.Zv2Label.Text = 'Zv2';
            app.Zv2EditField = uieditfield(app.Panel_7, 'numeric');
            app.vEditFieldLabel = uilabel(app.Panel_7);
            app.vEditFieldLabel.Text = 'v';
            app.vEditField = uieditfield(app.Panel_7, 'numeric');
            app.bhEditFieldLabel = uilabel(app.Panel_7);
            app.bhEditFieldLabel.Text = 'b/h';
            app.bhEditField = uieditfield(app.Panel_7, 'numeric');
            app.Kv1Label = uilabel(app.Panel_7);
            app.Kv1Label.Text = 'Kv';
            app.KvEditField = uieditfield(app.Panel_7, 'numeric');
            app.KvEditField.Value = 1.03;
            app.KFEditFieldLabel = uilabel(app.Panel_7);
            app.KFEditFieldLabel.Text = 'KFβ';
            app.KFEditField = uieditfield(app.Panel_7, 'numeric');
            app.KFEditField.Value = 1.28;
            app.b1EditFieldLabel = uilabel(app.Panel_7);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.Panel_7, 'numeric');
            app.KHLabel_2 = uilabel(app.Panel_7);
            app.KHLabel_2.Text = 'KH_β';
            app.KH_EditField_2 = uieditfield(app.Panel_7, 'numeric');
            app.Button_9 = uibutton(app.Tab_sjcl, 'state');
            app.Button_9.ValueChangedFcn = createCallbackFcn(app, @Button_9ValueChanged, true);
            app.Button_9.Enable = 'off';
            app.Button_9.Text = '下一步';
            app.run4Button = uibutton(app.Tab_sjcl, 'state');
            app.run4Button.ValueChangedFcn = createCallbackFcn(app, @run4ButtonValueChanged, true);
            app.run4Button.Enable = 'off';
            app.run4Button.Text = 'run4';
            app.run3Button = uibutton(app.Tab_sjcl, 'push');
            app.run3Button.ButtonPushedFcn = createCallbackFcn(app, @run3ButtonPushed, true);
            app.run3Button.Enable = 'off';
            app.run3Button.Text = 'run3';
            app.Label_48 = uilabel(app.Tab_sjcl);
            app.Label_48.Enable = 'off';
            app.Label_48.Text = '按齿面接触疲劳强度设计';
            app.Label_50 = uilabel(app.Tab_sjcl);
            app.Label_50.Enable = 'off';
            app.Label_50.Text = '按齿根弯曲疲劳强度设计';
            app.Tab_jsjg = uitab(app.TabGroup);
            app.Tab_jsjg.Title = '计算结果';
            app.Panel_5 = uipanel(app.Tab_jsjg);
            app.Panel_5.Enable = 'off';
            app.Panel_5.Title = '设计结果';
            app.Panel_19 = uipanel(app.Panel_5);
            app.Label_35 = uilabel(app.Panel_19);
            app.Label_35.Text = '大齿轮齿顶圆直径';
            app.EditField_68 = uieditfield(app.Panel_19, 'numeric');
            app.Label_34 = uilabel(app.Panel_19);
            app.Label_34.Text = '小齿轮齿顶圆直径';
            app.EditField_67 = uieditfield(app.Panel_19, 'numeric');
            app.EditField_71Label = uilabel(app.Panel_19);
            app.EditField_71Label.Text = '小齿轮采用';
            app.EditField_71 = uieditfield(app.Panel_19, 'text');
            app.Label_21 = uilabel(app.Panel_19);
            app.Label_21.Text = '小齿轮齿数';
            app.EditField_26 = uieditfield(app.Panel_19, 'numeric');
            app.Label_27 = uilabel(app.Panel_19);
            app.Label_27.Text = '小齿轮齿宽';
            app.EditField_32 = uieditfield(app.Panel_19, 'numeric');
            app.Label_32 = uilabel(app.Panel_19);
            app.Label_32.Text = '小齿轮分度圆直径';
            app.EditField_65 = uieditfield(app.Panel_19, 'numeric');
            app.Image5_2 = uiimage(app.Panel_19);
            app.Panel_20 = uipanel(app.Panel_5);
            app.Label_22 = uilabel(app.Panel_20);
            app.Label_22.Text = '大齿轮齿数';
            app.EditField_27 = uieditfield(app.Panel_20, 'numeric');
            app.Label_28 = uilabel(app.Panel_20);
            app.Label_28.Text = '大齿轮齿宽';
            app.EditField_34 = uieditfield(app.Panel_20, 'numeric');
            app.EditField_72Label = uilabel(app.Panel_20);
            app.EditField_72Label.Text = '大齿轮采用';
            app.EditField_72 = uieditfield(app.Panel_20, 'text');
            app.Label_33 = uilabel(app.Panel_20);
            app.Label_33.Text = '大齿轮分度圆直径';
            app.EditField_66 = uieditfield(app.Panel_20, 'numeric');
            app.Label_36 = uilabel(app.Panel_20);
            app.Label_36.Text = '小齿轮齿根圆直径';
            app.EditField_69 = uieditfield(app.Panel_20, 'numeric');
            app.Label_37 = uilabel(app.Panel_20);
            app.Label_37.Text = '大齿轮齿根圆直径';
            app.EditField_70 = uieditfield(app.Panel_20, 'numeric');
            app.Image5 = uiimage(app.Panel_20);
            app.Label_23 = uilabel(app.Panel_5);
            app.Label_23.Text = '法面模数';
            app.EditField_28 = uieditfield(app.Panel_5, 'numeric');
            app.Label_24 = uilabel(app.Panel_5);
            app.Label_24.Text = '压力角';
            app.EditField_29 = uieditfield(app.Panel_5, 'numeric');
            app.Label_25 = uilabel(app.Panel_5);
            app.Label_25.Text = '螺旋角';
            app.EditField_30 = uieditfield(app.Panel_5, 'numeric');
            app.Label_26 = uilabel(app.Panel_5);
            app.Label_26.Text = '中心距';
            app.EditField_31 = uieditfield(app.Panel_5, 'numeric');
            app.Label_30 = uilabel(app.Panel_5);
            app.Label_30.Text = '齿顶高';
            app.EditField_63 = uieditfield(app.Panel_5, 'numeric');
            app.Label_31 = uilabel(app.Panel_5);
            app.Label_31.Text = '齿根高';
            app.EditField_64 = uieditfield(app.Panel_5, 'numeric');
            app.Label_20 = uilabel(app.Panel_5);
            app.Label_20.Text = '实际传动比';
            app.EditField_33 = uieditfield(app.Panel_5, 'numeric');
            app.FrEditFieldLabel = uilabel(app.Panel_5);
            app.FrEditFieldLabel.Text = 'Fr';
            app.FrEditField = uieditfield(app.Panel_5, 'numeric');
            app.FtEditFieldLabel = uilabel(app.Panel_5);
            app.FtEditFieldLabel.Text = 'Ft';
            app.FtEditField = uieditfield(app.Panel_5, 'numeric');
            app.FaEditFieldLabel = uilabel(app.Panel_5);
            app.FaEditFieldLabel.Text = 'Fa';
            app.FaEditField = uieditfield(app.Panel_5, 'numeric');
            app.Panel_13 = uipanel(app.Tab_jsjg);
            app.Panel_13.Enable = 'off';
            app.Panel_13.Title = '初始参数';
            app.Label_42 = uilabel(app.Panel_13);
            app.Label_42.Text = '初选小齿轮齿数';
            app.EditField_73 = uieditfield(app.Panel_13, 'numeric');
            app.EditField_73.ValueChangedFcn = createCallbackFcn(app, @EditField_73ValueChanged, true);
            app.Label_43 = uilabel(app.Panel_13);
            app.Label_43.Text = '初选大齿轮齿数';
            app.EditField_74 = uieditfield(app.Panel_13, 'numeric');
            app.Label_44 = uilabel(app.Panel_13);
            app.Label_44.Text = '初选压力角';
            app.EditField_75 = uieditfield(app.Panel_13, 'numeric');
            app.Label_45 = uilabel(app.Panel_13);
            app.Label_45.Text = '初选螺旋角';
            app.EditField_76 = uieditfield(app.Panel_13, 'numeric');
            app.Label_46 = uilabel(app.Panel_13);
            app.Label_46.Text = '初始传动比';
            app.EditField_81 = uieditfield(app.Panel_13, 'numeric');
            app.Label_52 = uilabel(app.Panel_13);
            app.Label_52.Text = '小齿轮接触疲劳极限';
            app.EditField_83 = uieditfield(app.Panel_13, 'numeric');
            app.Label_53 = uilabel(app.Panel_13);
            app.Label_53.Text = '小齿轮弯曲疲劳极限';
            app.EditField_84 = uieditfield(app.Panel_13, 'numeric');
            app.Label_54 = uilabel(app.Panel_13);
            app.Label_54.Text = '大齿轮接触疲劳极限';
            app.EditField_85 = uieditfield(app.Panel_13, 'numeric');
            app.Label_55 = uilabel(app.Panel_13);
            app.Label_55.Text = '大齿轮弯曲疲劳极限';
            app.EditField_86 = uieditfield(app.Panel_13, 'numeric');
            app.Label_67 = uilabel(app.Panel_13);
            app.Label_67.Text = '输入功率';
            app.EditField_90 = uieditfield(app.Panel_13, 'numeric');
            app.Label_68 = uilabel(app.Panel_13);
            app.Label_68.Text = '齿顶高系数';
            app.EditField_91 = uieditfield(app.Panel_13, 'numeric');
            app.Label_69 = uilabel(app.Panel_13);
            app.Label_69.Text = '齿根高系数';
            app.EditField_92 = uieditfield(app.Panel_13, 'numeric');
            app.Button_10 = uibutton(app.Tab_jsjg, 'state');
            app.Button_10.ValueChangedFcn = createCallbackFcn(app, @Button_10ValueChanged, true);
            app.Button_10.Enable = 'off';
            app.Button_10.Text = '下一步';
            app.Tab_save = uitab(app.TabGroup);
            app.Tab_save.Title = '高级选项';
            app.Panel_9 = uipanel(app.Tab_save);
            app.TXTButton = uibutton(app.Panel_9, 'state');
            app.TXTButton.ValueChangedFcn = createCallbackFcn(app, @TXTButtonValueChanged, true);
            app.TXTButton.Enable = 'off';
            app.TXTButton.Text = '输出为TXT';
            app.WordButton = uibutton(app.Panel_9, 'state');
            app.WordButton.ValueChangedFcn = createCallbackFcn(app, @WordButtonValueChanged, true);
            app.WordButton.Enable = 'off';
            app.WordButton.Text = '输出为Word';
            app.Image4 = uiimage(app.Panel_9);
            app.Image4.ImageSource = '666.png';
            app.Button_13 = uibutton(app.Panel_9, 'state');
            app.Button_13.ValueChangedFcn = createCallbackFcn(app, @Button_13ValueChanged, true);
            app.Button_13.Tag = 'mesh';
            app.Button_13.Enable = 'off';
            app.Button_13.Text = '外啮合圆柱齿轮传动仿真';
            app.Button_14 = uibutton(app.Panel_9, 'state');
            app.Button_14.ValueChangedFcn = createCallbackFcn(app, @Button_14ValueChanged, true);
            app.Button_14.Tag = 'cy';
            app.Button_14.Enable = 'off';
            app.Button_14.Text = '小齿轮齿廓形成过程';
            app.Tab_shuoming = uitab(app.TabGroup);
            app.Tab_shuoming.Title = '软件说明';
            app.Panel_16 = uipanel(app.Tab_shuoming);
            app.Panel_16.Title = '功能及特点';
            app.Label_60 = uilabel(app.Panel_16);
            app.Label_60.Text = {'1、本系统旨在帮助用户设计渐开线圆柱齿轮传动系统，以满足各种机械传动需求；'; '2、提供了直观的用户界面，使参数输入和数据处理变得简单和便捷；'; '3、支持最常用的渐开线齿轮传动设计，为用户提供灵活且精确的参数选项；'; '4、可根据用户输入的参数自动生成齿轮参数计算结果和设计方案；'; '5、配套使用《机械设计第十版》可深入了解齿轮传动设计的背景知识和理论基础。'};
            app.Panel_17 = uipanel(app.Tab_shuoming);
            app.Panel_17.Title = '使用说明';
            app.TXTWordLabel = uilabel(app.Panel_17);
            app.TXTWordLabel.Text = {'1、启动系统后，用户将进入主界面，在此界面上可以输入相关的齿轮传动参数。'; '2、用户可自行查表确定粉色区域参数，确保输入的数据准确性；'; '3、用户输入参数后，软件将自动进行数据处理和齿轮参数的计算；'; '4、计算结果将显示在屏幕上，包括齿轮的几何特征、模数、齿轮数、齿距、齿轮的压力角、螺旋角等；'; '5、在计算结果中，用户还可以根据需要进行进一步的调整和优化；'; '6、用户可以保存设计方案，并在需要时进行导出TXT和Word。'};
            app.Panel_18 = uipanel(app.Tab_shuoming);
            app.Panel_18.Title = '注意事项';
            app.Label_61 = uilabel(app.Panel_18);
            app.Label_61.Text = {'1、因计算过程存在插值法、四舍五入等造成计算结果与课本存在偏差；'; '2、在实际应用中，建议用户进一步验证结果和优化设计方案；'; '3、在使用本软件前，请务必先熟悉《机械设计第十版》中相关章节，了解渐开线齿轮传动的基本原理和设计要点；'; '4、对于特殊或复杂的齿轮传动设计需求，建议咨询专业的机械工程师或进行进一步的研究。'};
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = '777.png';
            app.Label_47 = uilabel(app.UIFigure);
            app.Label_47.Text = '渐开线圆柱齿轮传动设计';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gear_Drive3_20260120165408

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end